classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        SumarButton               matlab.ui.control.Button
        RestarButton              matlab.ui.control.Button
        MultiplicarButton         matlab.ui.control.Button
        DesplazarButton           matlab.ui.control.Button
        InterpolarButton          matlab.ui.control.Button
        ReflejarButton            matlab.ui.control.Button
        resultLabel               matlab.ui.control.Label
        result                    matlab.ui.control.EditField
        Secuencia1Label           matlab.ui.control.Label
        Secuencia1                matlab.ui.control.EditField
        Secuencia2Label           matlab.ui.control.Label
        Secuencia2                matlab.ui.control.EditField
        Centro1Label              matlab.ui.control.Label
        Centro1                   matlab.ui.control.EditField
        Centro2Label              matlab.ui.control.Label
        Centro2                   matlab.ui.control.EditField
        CentroLabel               matlab.ui.control.Label
        Centro                    matlab.ui.control.EditField
        FactorLabel               matlab.ui.control.Label
        Factor                    matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        GrabarButton              matlab.ui.control.Button
        SeleccionararchivoButton  matlab.ui.control.Button
        DiezmacinButton           matlab.ui.control.Button
        AmplificarAtenuarButton   matlab.ui.control.Button
        ConvolucinButton          matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function GraficarButtonPushed(app, event)
     %murió
        end

        % Callback function
        function HolaMenuSelected(app, event)
            
            tutorialApp;
%             app.UIFigure = app.UIFigure2;
%             app.UIFigure.Position = [100 100 640 480];
%             app.UIFigure.Name = 'UI Figure2';

        end

        % Button pushed function: SumarButton
        function SumarButtonPushed(app, event)
            secuencia_1 = str2num(app.Secuencia1.Value);
            secuencia_2 = str2num(app.Secuencia2.Value);
            indice_1 = str2num(app.Centro1.Value);
            indice_2 = str2num(app.Centro2.Value);
            [NA, tam_1] = size(secuencia_1);
            [NA, tam_2] = size(secuencia_2);
            [ss,rr,mm] = SumaResta(secuencia_1, secuencia_2, indice_1, indice_2, tam_1, tam_2);
            set(app.result,'Value',num2str(ss));
                if indice_1 > indice_2
                    centroGrafica =  indice_1;
                else
                    centroGrafica =  indice_2;
                end
            sec = Separacioneje(centroGrafica,ss);            
           
            sep1 = Separacioneje(indice_1,secuencia_1);
            sep2 = Separacioneje(indice_2,secuencia_2);
            
           set(app.result,'Value',num2str(ss));  
           title(app.UIAxes, 'Secuencia1 + Secuencia2');
           %set(app.UIAxes,'Title','Secuencia1 + Secuencia2'); 
           set(app.Centro,'Value',num2str(centroGrafica)); 
           set(app.UIAxes,'XTick',sec);
           
           stem(app.UIAxes,sec,ss);
         
           figure(2)
           subplot(2,1,1);
           stem(sep1, secuencia_1);
           
           subplot(2,1,2);
           stem(sep2,secuencia_2);
        end

        % Button pushed function: RestarButton
        function RestarButtonPushed(app, event)
            secuencia_1 = str2num(app.Secuencia1.Value);
            secuencia_2 = str2num(app.Secuencia2.Value);
            indice_1 = str2num(app.Centro1.Value);
            indice_2 = str2num(app.Centro2.Value);
            [NA, tam_1] = size(secuencia_1);
            [NA, tam_2] = size(secuencia_2);
            [ss,rr,mm] = SumaResta(secuencia_1, secuencia_2, indice_1, indice_2, tam_1, tam_2);
            set(app.result,'Value',num2str(rr));
                if indice_1 > indice_2
                    centroGrafica =  indice_1;
                else
                    centroGrafica =  indice_2;
                end
            sec = Separacioneje(centroGrafica,rr);            
           
            sep1 = Separacioneje(indice_1,secuencia_1);
            sep2 = Separacioneje(indice_2,secuencia_2);
            
           set(app.result,'Value',num2str(rr));  
           title(app.UIAxes, 'Secuencia1 - Secuencia2');
           %set(app.Label,'Text','Secuencia1 - Secuencia2'); 
           set(app.Centro,'Value',num2str(centroGrafica)); 
           set(app.UIAxes,'XTick',sec);
           
           stem(app.UIAxes,sec,rr);
         
           figure(2)
           subplot(2,1,1);
           stem(sep1, secuencia_1);
           
           subplot(2,1,2);
           stem(sep2,secuencia_2);
        end

        % Button pushed function: MultiplicarButton
        function MultiplicarButtonPushed(app, event)
            secuencia_1 = str2num(app.Secuencia1.Value);
            secuencia_2 = str2num(app.Secuencia2.Value);
            indice_1 = str2num(app.Centro1.Value);
            indice_2 = str2num(app.Centro2.Value);
            [NA, tam_1] = size(secuencia_1);
            [NA, tam_2] = size(secuencia_2);
            [ss,rr,mm] = SumaResta(secuencia_1, secuencia_2, indice_1, indice_2, tam_1, tam_2);
            set(app.result,'Value',num2str(mm));
                if indice_1 > indice_2
                    centroGrafica =  indice_1;
                else
                    centroGrafica =  indice_2;
                end
            sec = Separacioneje(centroGrafica,mm);            
           
            sep1 = Separacioneje(indice_1,secuencia_1);
            sep2 = Separacioneje(indice_2,secuencia_2);
            
           set(app.result,'Value',num2str(mm)); 
           title(app.UIAxes, 'Secuencia1 X Secuencia2');
           %set(app.Label,'Text','Secuencia1 .* Secuencia2'); 
           set(app.Centro,'Value',num2str(centroGrafica)); 
           set(app.UIAxes,'XTick',sec);
           
           stem(app.UIAxes,sec,mm);
         
           figure(2)
           subplot(2,1,1);
           stem(sep1, secuencia_1);
           
           subplot(2,1,2);
           stem(sep2,secuencia_2);
        end

        % Button pushed function: DesplazarButton
        function DesplazarButtonPushed(app, event)
            secuencia_1 = str2num(app.Secuencia1.Value);
            secuencia_2 = str2num(app.Secuencia2.Value);
            indice_1 = str2num(app.Centro1.Value);
            indice_2 = str2num(app.Centro2.Value);
            factor = str2num(app.Factor.Value);
            [NA, tam_1] = size(secuencia_1);
            [NA, tam_2] = size(secuencia_2);
            [desplazamiento, new_indice_1] = Desplaza(secuencia_1, secuencia_2, indice_1, indice_2, tam_1, tam_2, factor);
            [desplazamiento2, new_indice_2] = Desplaza(secuencia_1, secuencia_2, indice_1, indice_2, tam_1, tam_2, factor);
            set(app.result,'Value',num2str(desplazamiento));            
            
            
            sec = Separacioneje(new_indice_1,desplazamiento);           
           
            sep1 = Separacioneje(indice_1,secuencia_1);
           % sep2 = Separacioneje(indice_2,secuencia_2);
            
           set(app.result,'Value',num2str(desplazamiento)); 
           title(app.UIAxes, ' Secuencia1 despalzada>>');
           %set(app.Label,'Text','         Secuencia1>>'); 
           set(app.Centro,'Value',num2str(new_indice_1)); 
           set(app.UIAxes,'XTick',sec);
           
           stem(app.UIAxes,sec,desplazamiento);
         
           figure(2)          
           stem(sep1, secuencia_1);           
          
            
        end

        % Button pushed function: ReflejarButton
        function ReflejarButtonPushed(app, event)
            secuencia_1 = str2num(app.Secuencia1.Value);
            secuencia_2 = str2num(app.Secuencia2.Value);
            indice_1 = str2num(app.Centro1.Value);
            indice_2 = str2num(app.Centro2.Value);
           
            
            [NA, tam_1] = size(secuencia_1);
            [NA, tam_2] = size(secuencia_2);
            
            new_index1 = tam_1-indice_1+1;
            new_index2 = tam_2-indice_2+1;


            sec = Separacioneje(new_index1,fliplr(secuencia_1));           
           
            sep1 = Separacioneje(indice_1,secuencia_1);
           % sep2 = Separacioneje(indice_2,secuencia_2);
            
           set(app.result,'Value',num2str(fliplr(secuencia_1)));   
           title(app.UIAxes, 'Secuencia1^(-1)');
           %set(app.Label,'Text','         Secuencia1^-1'); 
           set(app.Centro,'Value',num2str(new_index1)); 
           set(app.UIAxes,'XTick',sec);
           
           stem(app.UIAxes,sec,fliplr(secuencia_1));
         
           figure(2)          
           stem(sep1, secuencia_1);     
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
                recObj = audiorecorder;
                set(app.Label,'Text','Grabando...'); 
                recordblocking(recObj, 5);
                set(app.Label,'Text','Fin de grabación');
               
                y = getaudiodata(recObj);
                disp(y);
                sound(y,8000);
                cd C:\Users\Mario\Desktop
                filename = 'archivo.wav';
                audiowrite(filename,y,8000);
                tutorialApp;
               
                             
        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            [file, path] = uigetfile({'*.mp3; *.wav'},'Seleccione el archivo de audio');
            
            [y,Fs] = audioread(strcat(path,file));
            cd C:\Users\Mario\Desktop
            filename = 'archivo.wav';
            audiowrite(filename,y,Fs);
            disp(Fs);
            tutorialApp;
        end

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
        secuencia_1 = str2num(app.Secuencia1.Value);
        secuencia_2 = str2num(app.Secuencia2.Value);
        indice_1 = str2num(app.Centro1.Value);
        indice_2 = str2num(app.Centro2.Value);
        factor = str2num(app.Factor.Value);
        
        [NA, tam_1] = size(secuencia_1);
        [NA, tam_2] = size(secuencia_2);
        
        [cero, escalon, lineal] = Interpolacion(secuencia_1, secuencia_2, indice_1, indice_2, tam_1, tam_2, factor);
        
        new_indice = (factor * (indice_1 - 1)) + 1;
        
        
        sec = Separacioneje(new_indice,cero);         
        sep1 = Separacioneje(new_indice,escalon);
        sep2 = Separacioneje(new_indice,lineal);
        sep3 = Separacioneje(indice_1,secuencia_1);
       set(app.result,'Value',num2str(lineal));   
       title(app.UIAxes, 'Interpolación lineal:Secuencia1');
       %set(app.Label,'Text','         Secuencia1^-1'); 
       set(app.Centro,'Value',num2str(new_indice)); 
       set(app.UIAxes,'XTick',sec);
           
       stem(app.UIAxes,sec,lineal);
         
       figure(2)
       subplot(2,1,1);
       stem(sep1, cero);
       
       subplot(2,1,2);
       stem(sep2,escalon); 
       
       figure(3)
           
       stem(sep3, secuencia_1);
           
          
        end

        % Button pushed function: DiezmacinButton
        function DiezmacinButtonPushed(app, event)
        secuencia_1 = str2num(app.Secuencia1.Value);       
        indice_1 = str2num(app.Centro1.Value);        
        factor = str2num(app.Factor.Value);
        [NA, tam_1] = size(secuencia_1);
        
        [diezmacion, new_indice] = Diezma(secuencia_1, indice_1, factor, tam_1);
         
        sep1 = Separacioneje(new_indice,diezmacion);
        sep2 = Separacioneje(indice_1,secuencia_1);
        
       set(app.result,'Value',num2str(diezmacion));   
       title(app.UIAxes, 'Diezmación: Secuencia1');
       set(app.Centro,'Value',num2str(new_indice)); 
       set(app.UIAxes,'XTick',sep1)
       
       stem(app.UIAxes,sep1,diezmacion);
       
       figure(2)          
           stem(sep2, secuencia_1); 
        end

        % Button pushed function: AmplificarAtenuarButton
        function AmplificarAtenuarButtonPushed(app, event)
            secuencia_1 = str2num(app.Secuencia1.Value);
            indice_1 = str2num(app.Centro1.Value);          
            factor = str2num(app.Factor.Value);
            
            [NA, tam_1] = size(secuencia_1);
            sep1 = Separacioneje(indice_1,secuencia_1);
             
           set(app.result,'Value',num2str(secuencia_1 * factor));   
           title(app.UIAxes, 'Secuencia1 * factor');
           %set(app.Label,'Text','         Secuencia1^-1'); 
           set(app.Centro,'Value',num2str(indice_1)); 
           set(app.UIAxes,'XTick',sep1);
           
           stem(app.UIAxes,sep1, secuencia_1 * factor);
         
           figure(2)          
           stem(sep1, secuencia_1);

        end

        % Button pushed function: ConvolucinButton
        function ConvolucinButtonPushed(app, event)
        secuencia_1 = str2num(app.Secuencia1.Value);
        secuencia_2 = str2num(app.Secuencia2.Value);
        indice_1 = str2num(app.Centro1.Value);
        indice_2 = str2num(app.Centro2.Value);
        
        sep2 = Separacioneje(indice_1,secuencia_1);
        sep3 = Separacioneje(indice_2,secuencia_2);
        
        [NA, tam_1] = size(secuencia_1);
        [NA, tam_2] = size(secuencia_2);
            
         new_indice =  indice_1 + indice_2 - 1;   
            
            tipo = menu('Tipo', 'Convolucion finita', 'Convolucion periodica', 'Convolucion circular');
        
        switch tipo
            case 1
                finita = ConvFini(secuencia_1, secuencia_2, tam_1, tam_2, indice_1, indice_2);
                sep = Separacioneje(new_indice,finita);
                
                   set(app.result,'Value',num2str(finita));   
                   title(app.UIAxes, 'Secuencia1 * Secuencia2: Finitas');
                   %set(app.Label,'Text','         Secuencia1^-1'); 
                   set(app.Centro,'Value',num2str(new_indice)); 
                   set(app.UIAxes,'XTick',sep);
                   
                   stem(app.UIAxes,sep,finita);
                   
                   figure(2)
                   subplot(2,1,1);
                   stem(sep2, secuencia_1);
                   
                   subplot(2,1,2);
                   stem(sep3,secuencia_2); 
           
                 
            case 2
                finita = ConvFini(secuencia_1, secuencia_2, tam_1, tam_2, indice_1, indice_2);
                periodica = ConvPeri(finita, tam_2);
                
                
                %%%%%
                [NA, tam_peri]= size(periodica);
                aux = periodica;
                for w = 1:2
                    periodica(w*tam_peri + 1: (w*tam_peri) + tam_peri)=aux(:);
                end
                new_indice = new_indice +  tam_peri;
                %%%%%
                sep = Separacioneje(new_indice,periodica);
                
                   set(app.result,'Value',num2str(aux));   
                   title(app.UIAxes, 'Secuencia1 * Secuencia2: Convolución periódica');
                   %set(app.Label,'Text','         Secuencia1^-1'); 
                   set(app.Centro,'Value',num2str(new_indice - tam_peri)); 
                   set(app.UIAxes,'XTick',sep);
                   
                   stem(app.UIAxes,sep,periodica);
                   
                   figure(2)
                   subplot(2,1,1);
                   stem(sep2, secuencia_1);
                   
                   subplot(2,1,2);
                   stem(sep3,secuencia_2);
            case 3
                if tam_1 > tam_2
                    finita = ConvFini(secuencia_1, secuencia_2, tam_1, tam_2, indice_1, indice_2);
                    periodica = ConvPeri(finita, tam_1);
                else
                    finita = ConvFini(secuencia_2, secuencia_1, tam_2, tam_1, indice_2, indice_1);
                    periodica = ConvPeri(finita, tam_2);
                end
                
               %%%%%
                [NA, tam_peri]= size(periodica);
                aux = periodica;
                for w = 1:2
                    periodica(w*tam_peri + 1: (w*tam_peri) + tam_peri)=aux(:);
                end
                new_indice = new_indice +  tam_peri;
                %%%%%
                sep = Separacioneje(new_indice,periodica);
                
                   set(app.result,'Value',num2str(aux));   
                   title(app.UIAxes, 'Secuencia1 * Secuencia2: Convolución circular');
                   %set(app.Label,'Text','         Secuencia1^-1'); 
                   set(app.Centro,'Value',num2str(new_indice - tam_peri)); 
                   set(app.UIAxes,'XTick',sep);
                   
                   stem(app.UIAxes,sep,periodica);
                   
                   figure(2)
                   subplot(2,1,1);
                   stem(sep2, secuencia_1);
                   
                   subplot(2,1,2);
                   stem(sep3,secuencia_2);
             
        end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 1 2 3];
            app.SumarButton = uibutton(app.UIFigure, 'push');
            app.SumarButton.ButtonPushedFcn = createCallbackFcn(app, @SumarButtonPushed, true);
            app.SumarButton.Text = 'Sumar';
            app.RestarButton = uibutton(app.UIFigure, 'push');
            app.RestarButton.ButtonPushedFcn = createCallbackFcn(app, @RestarButtonPushed, true);
            app.RestarButton.Text = 'Restar';
            app.MultiplicarButton = uibutton(app.UIFigure, 'push');
            app.MultiplicarButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicarButtonPushed, true);
            app.MultiplicarButton.Text = 'Multiplicar';
            app.DesplazarButton = uibutton(app.UIFigure, 'push');
            app.DesplazarButton.ButtonPushedFcn = createCallbackFcn(app, @DesplazarButtonPushed, true);
            app.DesplazarButton.Text = 'Desplazar';
            app.InterpolarButton = uibutton(app.UIFigure, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Text = 'Interpolar';
            app.ReflejarButton = uibutton(app.UIFigure, 'push');
            app.ReflejarButton.ButtonPushedFcn = createCallbackFcn(app, @ReflejarButtonPushed, true);
            app.ReflejarButton.Text = 'Reflejar';
            app.resultLabel = uilabel(app.UIFigure);
            app.resultLabel.Text = 'result:';
            app.result = uieditfield(app.UIFigure, 'text');
            app.Secuencia1Label = uilabel(app.UIFigure);
            app.Secuencia1Label.Text = 'Secuencia 1:';
            app.Secuencia1 = uieditfield(app.UIFigure, 'text');
            app.Secuencia2Label = uilabel(app.UIFigure);
            app.Secuencia2Label.Text = 'Secuencia 2:';
            app.Secuencia2 = uieditfield(app.UIFigure, 'text');
            app.Centro1Label = uilabel(app.UIFigure);
            app.Centro1Label.Text = 'Centro 1:';
            app.Centro1 = uieditfield(app.UIFigure, 'text');
            app.Centro2Label = uilabel(app.UIFigure);
            app.Centro2Label.Text = 'Centro 2:';
            app.Centro2 = uieditfield(app.UIFigure, 'text');
            app.CentroLabel = uilabel(app.UIFigure);
            app.CentroLabel.Text = 'Centro:';
            app.Centro = uieditfield(app.UIFigure, 'text');
            app.FactorLabel = uilabel(app.UIFigure);
            app.FactorLabel.Text = 'Factor:';
            app.Factor = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.SeleccionararchivoButton = uibutton(app.UIFigure, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.DiezmacinButton = uibutton(app.UIFigure, 'push');
            app.DiezmacinButton.ButtonPushedFcn = createCallbackFcn(app, @DiezmacinButtonPushed, true);
            app.DiezmacinButton.Text = 'Diezmación';
            app.AmplificarAtenuarButton = uibutton(app.UIFigure, 'push');
            app.AmplificarAtenuarButton.ButtonPushedFcn = createCallbackFcn(app, @AmplificarAtenuarButtonPushed, true);
            app.AmplificarAtenuarButton.Text = 'Amplificar/Atenuar';
            app.ConvolucinButton = uibutton(app.UIFigure, 'push');
            app.ConvolucinButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolucinButtonPushed, true);
            app.ConvolucinButton.Text = 'Convolución';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end