classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure2                 matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        PlayButton                matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        ReflejoButton             matlab.ui.control.Button
        FactorEditFieldLabel      matlab.ui.control.Label
        FactorC                   matlab.ui.control.EditField
        DesplazamientoButton      matlab.ui.control.Button
        InterpolacinlinealButton  matlab.ui.control.Button
        InterpolacinaceroButton   matlab.ui.control.Button
        InterpolacinescalnButton  matlab.ui.control.Button
        DiezmacinButton           matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function AmplitudSliderValueChanged(app, event)
            value = app.AmplitudSlider.Value;
            plot(app.UIAxes, value*peaks);
            app.UIAxes.YLim = [-1000 1000];
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            [y,Fs] = audioread('archivo.wav');
                        
%             A = y';
%             A=fliplr(A);
%             A= A';
            disp(Fs);
            stem(app.UIAxes,y);
            sound(real(double(y)),Fs);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound
        end

        % Button pushed function: ReflejoButton
        function ReflejoButtonPushed(app, event)
             [y,Fs] = audioread('archivo.wav');
                        
            A = y';
            A=fliplr(A);
            A= A';
            stem(app.UIAxes2,A);
            sound(real(double(A)),Fs);
        end

        % Button pushed function: DesplazamientoButton
        function DesplazamientoButtonPushed(app, event)
            [y,Fs] = audioread('archivo.wav');
                        
            A = y';
            
            [NA, tam_1] = size(A);
            factor = str2num(app.FactorC.Value);
            [desplazamiento, new_indice_1] = Desplaza(A, 0, 1, 0, tam_1, 0, factor);
            
          
            [NA, tam_final] = size(desplazamiento);
            
            disp(length(1 :tam_final - new_indice_1));
            disp(length(new_indice_1 : tam_final));
            
            A(1 :tam_final - new_indice_1 + 1) = desplazamiento(new_indice_1 : tam_final);
            A = A';
            stem(app.UIAxes2,A);
            sound(real(double(A)),Fs);
        end

        % Button pushed function: InterpolacinlinealButton
        function InterpolacinlinealButtonPushed(app, event)
             [y,Fs] = audioread('archivo.wav');
                        
            A = y';
            
            [NA, tam_1] = size(A);
            factor = str2num(app.FactorC.Value);
            [cero, escalon, lineal] = Interpolacion(A, 0, 1, 0, tam_1, 0, factor);
            
            A= lineal';
            stem(app.UIAxes2,A);
            sound(real(double(A)),Fs);
        end

        % Button pushed function: InterpolacinaceroButton
        function InterpolacinaceroButtonPushed(app, event)
             [y,Fs] = audioread('archivo.wav');
                        
            A = y';
            
            [NA, tam_1] = size(A);
            factor = str2num(app.FactorC.Value);
            [cero, escalon, lineal] = Interpolacion(A, 0, 1, 0, tam_1, 0, factor);
            
            A= cero';
            stem(app.UIAxes2,A);
            sound(real(double(A)),Fs);
        end

        % Button pushed function: InterpolacinescalnButton
        function InterpolacinescalnButtonPushed(app, event)
            [y,Fs] = audioread('archivo.wav');
                        
            A = y';
            
            [NA, tam_1] = size(A);
            factor = str2num(app.FactorC.Value);
            [cero, escalon, lineal] = Interpolacion(A, 0, 1, 0, tam_1, 0, factor);
            
            B(:)= escalon(:);
            stem(app.UIAxes2,B');
            sound(real(double(B')),Fs);
            
            
        end

        % Button pushed function: DiezmacinButton
        function DiezmacinButtonPushed(app, event)
            [y,Fs] = audioread('archivo.wav');
                        
            A = y';
            
            [NA, tam_1] = size(A);
            factor = str2num(app.FactorC.Value);
            indice_1 = round(tam_1/2);
            [diezmacion, new_indice] = Diezma(A, indice_1, factor, tam_1);
            
            A= diezmacion';
            stem(app.UIAxes2,A);
            sound(real(double(A)),Fs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure2 = uifigure;
            app.UIFigure2.Name = 'UI Figure2';
            app.UIAxes = uiaxes(app.UIFigure2);
            title(app.UIAxes, 'Señal original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure2);
            title(app.UIAxes2, 'Señal alterada')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PlayButton = uibutton(app.UIFigure2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ReflejoButton = uibutton(app.UIFigure2, 'push');
            app.ReflejoButton.ButtonPushedFcn = createCallbackFcn(app, @ReflejoButtonPushed, true);
            app.ReflejoButton.Text = 'Reflejo';
            app.FactorEditFieldLabel = uilabel(app.UIFigure2);
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorC = uieditfield(app.UIFigure2, 'text');
            app.DesplazamientoButton = uibutton(app.UIFigure2, 'push');
            app.DesplazamientoButton.ButtonPushedFcn = createCallbackFcn(app, @DesplazamientoButtonPushed, true);
            app.DesplazamientoButton.Text = 'Desplazamiento';
            app.InterpolacinlinealButton = uibutton(app.UIFigure2, 'push');
            app.InterpolacinlinealButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinlinealButtonPushed, true);
            app.InterpolacinlinealButton.Text = 'Interpolación lineal';
            app.InterpolacinaceroButton = uibutton(app.UIFigure2, 'push');
            app.InterpolacinaceroButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinaceroButtonPushed, true);
            app.InterpolacinaceroButton.Text = 'Interpolación a cero';
            app.InterpolacinescalnButton = uibutton(app.UIFigure2, 'push');
            app.InterpolacinescalnButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolacinescalnButtonPushed, true);
            app.InterpolacinescalnButton.Text = 'Interpolación escalón';
            app.DiezmacinButton = uibutton(app.UIFigure2, 'push');
            app.DiezmacinButton.ButtonPushedFcn = createCallbackFcn(app, @DiezmacinButtonPushed, true);
            app.DiezmacinButton.Text = 'Diezmación';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure2)
        end
    end
end